classdef Task1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GetImageButton                matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        Bellow60HappyAbove60SadLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, button, axes)
            global value;
            global happy_image;
            global sad_image;
            
            value=0;
            sad_image=imread("sad.png");
            happy_image=imread("smile.png");
            imshow("neutral.png",'Parent',app.UIAxes);
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            global value;
            global sad_image;
            global happy_image;
            if(value<60)
                imshow(happy_image,'Parent',app.UIAxes);
            else
                imshow(sad_image,'Parent',app.UIAxes);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            global value;
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Bellow60HappyAbove60SadLabel = uilabel(app.UIFigure);
            app.Bellow60HappyAbove60SadLabel.Text = {'Bellow 60-->Happy'; 'Above 60-->Sad'};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end