classdef Topic2_Task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        result_field                matlab.ui.control.NumericEditField
        error_field                 matlab.ui.control.EditField
        operator1                   matlab.ui.control.EditField
        EditField_6                 matlab.ui.control.EditField
        EditField_5                 matlab.ui.control.EditField
        EditField_4                 matlab.ui.control.EditField
        DeleteButton                matlab.ui.control.Button
        EnterButton                 matlab.ui.control.Button
        EditField_3                 matlab.ui.control.EditField
        ErrorconsoleLabel_2         matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        EditField_2                 matlab.ui.control.EditField
        EqualButton                 matlab.ui.control.Button
        PlottingoptionsButtonGroup  matlab.ui.container.ButtonGroup
        equationButton              matlab.ui.control.RadioButton
        cosineButton                matlab.ui.control.RadioButton
        sineButton                  matlab.ui.control.RadioButton
        resultantButton             matlab.ui.control.RadioButton
        xyaxisButton                matlab.ui.control.RadioButton
        yaxisButton                 matlab.ui.control.RadioButton
        xaxisButton                 matlab.ui.control.RadioButton
        resultLabel                 matlab.ui.control.Label
        parameter2                  matlab.ui.control.NumericEditField
        parameter1                  matlab.ui.control.NumericEditField
        XAxisLabel                  matlab.ui.control.Label
        YAxisLabel                  matlab.ui.control.Label
        EditField                   matlab.ui.control.EditField
        EnterEquationfunxoryxLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        listOperations1             matlab.ui.control.ListBox
        ArithmaticOperationsLabel   matlab.ui.control.Label
        ErrorconsoleLabel           matlab.ui.control.Label
        SimpleCalculatorLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            global result;
            global Error;
            result=0;
            Error='No Error';
        end

        % Value changed function: error_field
        function error_fieldValueChanged(app, event)
            value = app.error_field.Value;
            
        end

        % Value changing function: error_field
        function error_fieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: parameter1
        function parameter1ValueChanged(app, event)
            value = app.parameter1.Value;
            
        end

        % Value changed function: operator1
        function operator1ValueChanged(app, event)
            value = app.operator1.Value;
            
        end

        % Value changing function: operator1
        function operator1ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: parameter2
        function parameter2ValueChanged(app, event)
            value = app.parameter2.Value;
            
        end

        % Value changed function: result_field
        function result_fieldValueChanged(app, event)
            value = app.result_field.Value;
            
        end

        % Value changed function: listOperations1
        function listOperations1ValueChanged(app, event)
            value = app.listOperations1.Value;
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            local_result=0;
         app.operator1.Value=app.listOperations1.Value;
         local_operator=app.operator1.Value;
         if(local_operator=='+')
             local_result=app.parameter1.Value+app.parameter2.Value;
              app.result_field.Value=local_result;
              
              app.error_field.Value='No error';
         elseif(local_operator=='-')
            local_result= app.parameter1.Value-app.parameter2.Value;
             app.result_field.Value=local_result;

             app.error_field.Value='No error';
         elseif(local_operator=='/')
             if(app.parameter2.Value==0)
                 app.error_field.Value='Cant be divided by Zero';
                 app.result_field.Value=0;
             else
                 local_result=app.parameter1.Value/app.parameter2.Value;
                  app.result_field.Value=local_result;
    
                  app.error_field.Value='No error';
             end
         elseif(local_operator=='*')
             local_result=app.parameter1.Value*app.parameter2.Value;
              app.result_field.Value=local_result;

              app.error_field.Value='No error';
         else
             app.error_field.Value='Error: wrong operator';
         end
        end

        % Selection changed function: PlottingoptionsButtonGroup
        function PlottingoptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.PlottingoptionsButtonGroup.SelectedObject;
            
        end

        % Size changed function: PlottingoptionsButtonGroup
        function PlottingoptionsButtonGroupSizeChanged(app, event)
            position = app.PlottingoptionsButtonGroup.Position;
            
        end

        % Button down function: PlottingoptionsButtonGroup
        function PlottingoptionsButtonGroupButtonDown(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Value changing function: EditField_2
        function EditField_2ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value = app.EditField_6.Value;
            
        end

        % Value changing function: EditField_6
        function EditField_6ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changing function: EditField_3
        function EditField_3ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Value changing function: EditField_4
        function EditField_4ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Value changing function: EditField_5
        function EditField_5ValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot area')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.ErrorconsoleLabel = uilabel(app.UIFigure);
            app.ErrorconsoleLabel.Text = 'Error console';
            app.ArithmaticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmaticOperationsLabel.Text = 'Arithmatic Operations';
            app.listOperations1 = uilistbox(app.UIFigure);
            app.listOperations1.Items = {'+', '-', '*', '/'};
            app.listOperations1.ValueChangedFcn = createCallbackFcn(app, @listOperations1ValueChanged, true);
            app.listOperations1.Value = '+';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '=';
            app.EnterEquationfunxoryxLabel = uilabel(app.UIFigure);
            app.EnterEquationfunxoryxLabel.Text = 'Enter Equation fun(x) or y(x)';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y Axis ';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X Axis';
            app.parameter1 = uieditfield(app.UIFigure, 'numeric');
            app.parameter1.ValueChangedFcn = createCallbackFcn(app, @parameter1ValueChanged, true);
            app.parameter2 = uieditfield(app.UIFigure, 'numeric');
            app.parameter2.ValueChangedFcn = createCallbackFcn(app, @parameter2ValueChanged, true);
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Text = 'result ';
            app.PlottingoptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingoptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingoptionsButtonGroupSelectionChanged, true);
            app.PlottingoptionsButtonGroup.Title = 'Plotting options';
            app.PlottingoptionsButtonGroup.SizeChangedFcn = createCallbackFcn(app, @PlottingoptionsButtonGroupSizeChanged, true);
            app.PlottingoptionsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @PlottingoptionsButtonGroupButtonDown, true);
            app.xaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.xaxisButton.Text = 'x axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.yaxisButton.Text = 'y axis';
            app.xyaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.xyaxisButton.Text = 'xy axis';
            app.resultantButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.resultantButton.Text = 'resultant';
            app.sineButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.sineButton.Text = 'sine';
            app.cosineButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.cosineButton.Text = 'cosine';
            app.equationButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.equationButton.Text = 'equation';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @EditField_2ValueChanging, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'+', '-', '*', '/'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '+';
            app.ErrorconsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorconsoleLabel_2.Text = 'Error console';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.ValueChangingFcn = createCallbackFcn(app, @EditField_3ValueChanging, true);
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.ValueChangingFcn = createCallbackFcn(app, @EditField_4ValueChanging, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.ValueChangingFcn = createCallbackFcn(app, @EditField_5ValueChanging, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.ValueChangingFcn = createCallbackFcn(app, @EditField_6ValueChanging, true);
            app.operator1 = uieditfield(app.UIFigure, 'text');
            app.operator1.ValueChangedFcn = createCallbackFcn(app, @operator1ValueChanged, true);
            app.operator1.ValueChangingFcn = createCallbackFcn(app, @operator1ValueChanging, true);
            app.error_field = uieditfield(app.UIFigure, 'text');
            app.error_field.ValueChangedFcn = createCallbackFcn(app, @error_fieldValueChanged, true);
            app.error_field.ValueChangingFcn = createCallbackFcn(app, @error_fieldValueChanging, true);
            app.result_field = uieditfield(app.UIFigure, 'numeric');
            app.result_field.ValueChangedFcn = createCallbackFcn(app, @result_fieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Topic2_Task2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end